classdef cordouan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        cordouanGUI                     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataIO                          matlab.ui.container.Tab
        SetDefaultButton                matlab.ui.control.Button
        TRTSCheckBox                    matlab.ui.control.CheckBox
        ZeroPadding                     matlab.ui.control.NumericEditField
        ZeroPaddingLabel                matlab.ui.control.Label
        WindowWidthpsEditField          matlab.ui.control.NumericEditField
        WindowWidthpsEditFieldLabel     matlab.ui.control.Label
        WindowingDropDown               matlab.ui.control.DropDown
        WindowingDropDownLabel          matlab.ui.control.Label
        FlipAmplitudeCheckBox           matlab.ui.control.CheckBox
        FlipTimeAxisCheckBox            matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        LOADPLOTButton                  matlab.ui.control.Button
        OutputFilenameEditField         matlab.ui.control.EditField
        OutputFilenameEditFieldLabel    matlab.ui.control.Label
        ReferenceEditField              matlab.ui.control.EditField
        ReferenceEditFieldLabel         matlab.ui.control.Label
        SampleEditField                 matlab.ui.control.EditField
        SampleEditFieldLabel            matlab.ui.control.Label
        OutputFileDirectoryEditField    matlab.ui.control.EditField
        OutputFileDirectoryEditFieldLabel  matlab.ui.control.Label
        InputFileDirectoryEditField     matlab.ui.control.EditField
        InputFileDirectoryEditFieldLabel  matlab.ui.control.Label
        vButton_ReferenceFile           matlab.ui.control.Button
        vButton_SampleFile              matlab.ui.control.Button
        BrowseButton_OutputDir          matlab.ui.control.Button
        BrowseButton_InputDir           matlab.ui.control.Button
        Power_plot                      matlab.ui.control.UIAxes
        TD_plot                         matlab.ui.control.UIAxes
        RunParam                        matlab.ui.container.Tab
        SaveInputFileButton             matlab.ui.control.Button
        AmplCutoffEditField             matlab.ui.control.NumericEditField
        AmplitudeCutoffLabel            matlab.ui.control.Label
        TreeLabel                       matlab.ui.control.Label
        LoadInputFileButton             matlab.ui.control.Button
        TFBuilderLabel                  matlab.ui.control.Label
        InputFileEditField              matlab.ui.control.EditField
        InputFileEditFieldLabel         matlab.ui.control.Label
        BrowseInputFileButton           matlab.ui.control.Button
        ReferenceGeometryTextArea       matlab.ui.control.TextArea
        ReferenceGeometryTextAreaLabel  matlab.ui.control.Label
        SampleGeometryTextArea          matlab.ui.control.TextArea
        SampleGeometryTextAreaLabel     matlab.ui.control.Label
        FreqStepEditField               matlab.ui.control.NumericEditField
        FreqStepTHzEditFieldLabel       matlab.ui.control.Label
        MaxFreqEditField                matlab.ui.control.NumericEditField
        MaxFreqTHzEditFieldLabel        matlab.ui.control.Label
        MinFreqEditField                matlab.ui.control.NumericEditField
        MinFreqTHzEditFieldLabel        matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        TransferFunctionBuilderLabel    matlab.ui.control.Label
        FrequencyRangeLabel             matlab.ui.control.Label
        DataAnalysis                    matlab.ui.container.Tab
        PlotTypes                       matlab.ui.container.TabGroup
        nk                              matlab.ui.container.Tab
        nk_SaveFigureButton             matlab.ui.control.Button
        nk_SaveDataButton               matlab.ui.control.Button
        nk_Plot                         matlab.ui.control.UIAxes
        Permittivty                     matlab.ui.container.Tab
        Permittivity_SaveFigureButton   matlab.ui.control.Button
        Permittivity_SaveDataButton     matlab.ui.control.Button
        Permittivity_Plot               matlab.ui.control.UIAxes
        AbsCoeff                        matlab.ui.container.Tab
        AbsCoeff_SaveFigureButton       matlab.ui.control.Button
        AbsCoeff_SaveDataButton         matlab.ui.control.Button
        AbsCoeff_Plot                   matlab.ui.control.UIAxes
        Diagnostics                     matlab.ui.container.Tab
        DiagnosticsTF_SaveDataButton    matlab.ui.control.Button
        DiagnosticsSpectra_SaveDataButton  matlab.ui.control.Button
        SpectraPhase_Plot               matlab.ui.control.UIAxes
        SpectraMag_Plot                 matlab.ui.control.UIAxes
        TFphase_Plot                    matlab.ui.control.UIAxes
        TFmag_Plot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Sets up variables that can be shared throughout the app (in between seperate callbacks)
        freq
        n_fit
        abs_coeff
        eps
        freq_full
        tf_full
        tf_spec
        tf_pred
        spec_smp
        spec_ref
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Data IO
            try % Load initGUI.json
                str = which('nelly_main');
                path = str(1:end-12);
                if ~isdeployed
                    addpath(fullfile(path, 'utilities'))
                end
                test_data_path = fullfile(path, 'test_data');
                if isdeployed
                    test_data_path = fullfile(ctfroot,'cordouan', 'test_data');
                    if ~(exist('test_data','dir') == 7)
                        test_data_path = fullfile(path, 'test_data');
                    end
                end
                % IO Param
                text = fileread(fullfile(path,'initGUI.json'));
                no_comments_init = regexprep(text, '/.*?\n', '\n');
                init = jsondecode(no_comments_init);
                if isempty(init.io.input_dir)
                    param = load_input(fullfile(test_data_path, 'cell_ref_filled_input.json'));
                    app.InputFileDirectoryEditField.Value = fullfile(test_data_path, filesep);
                    app.OutputFileDirectoryEditField.Value = fullfile(test_data_path, 'output', filesep);
                    app.InputFileEditField.Value = fullfile(test_data_path, 'cell_ref_filled_input.json');
                else
                    param = load_input(init.run.file);
                    app.InputFileDirectoryEditField.Value = init.io.input_dir;
                    app.OutputFileDirectoryEditField.Value = init.io.output_dir;
                    app.InputFileEditField.Value = init.run.file;
                end
                app.SampleEditField.Value = init.io.sample;
                app.ReferenceEditField.Value = init.io.reference;
                app.OutputFilenameEditField.Value = init.io.output;
                app.FlipTimeAxisCheckBox.Value = init.io.flip_time;
                app.FlipAmplitudeCheckBox.Value = init.io.flip_ampl;
                app.TRTSCheckBox.Value = init.io.TRTS;
                
                % Run Param
                app.WindowingDropDown.Value = param.settings.fft.windowing_type;
                app.WindowWidthpsEditField.Value = param.settings.fft.windowing_width;
                app.ZeroPadding.Value = param.settings.fft.padding;
                app.MinFreqEditField.Value = param.settings.freq_lo;
                app.MaxFreqEditField.Value = param.settings.freq_hi;
                app.FreqStepEditField.Value = param.settings.freq_step;
                app.AmplCutoffEditField.Value = param.settings.a_cut;
                
                app.SampleGeometryTextArea.Value = tf_interpreter_gui(param.sample);
                app.ReferenceGeometryTextArea.Value = tf_interpreter_gui(param.reference);  
            catch ME % If it breaks or does not exist, load default parameters
                errordlg([path '\n' test_data_path '\n' ME.message],'Initialization Error');
                param = load_input(fullfile(test_data_path, 'cell_ref_filled_input.json'));
                app.InputFileDirectoryEditField.Value = fullfile(test_data_path, filesep);
                app.OutputFileDirectoryEditField.Value = fullfile(test_data_path, 'output', filesep);
                app.SampleEditField.Value = 'cell_ref_filled.tim';
                app.ReferenceEditField.Value = 'cell_ref_empty.tim';
                app.OutputFilenameEditField.Value = 'cell_filled_test_output';
                app.FlipTimeAxisCheckBox.Value = false;
                app.FlipAmplitudeCheckBox.Value = false;
                app.TRTSCheckBox.Value = false;
                
                % Run Param
                app.WindowingDropDown.Value = param.settings.fft.windowing_type;
                app.WindowWidthpsEditField.Value = param.settings.fft.windowing_width;
                app.ZeroPadding.Value = param.settings.fft.padding;
                app.MinFreqEditField.Value = param.settings.freq_lo;
                app.MaxFreqEditField.Value = param.settings.freq_hi;
                app.FreqStepEditField.Value = param.settings.freq_step;
                app.AmplCutoffEditField.Value = param.settings.a_cut;
                app.InputFileEditField.Value = [path,'test_data/cell_ref_filled_input.json'];
                
                app.SampleGeometryTextArea.Value = tf_interpreter_gui(param.sample);
                app.ReferenceGeometryTextArea.Value = tf_interpreter_gui(param.reference);
            end
        end

        % Button pushed function: LOADPLOTButton
        function LOADPLOTButtonPushed(app, event)
            try
                % Try to load and plot data
                cla(app.TD_plot); % Clears TD plot
                cla(app.Power_plot); % Clears power spectrum plot
                
                % Import data
                [t_sample_TD, A_sample_TD] = Import_for_timfiles([app.InputFileDirectoryEditField.Value,app.SampleEditField.Value]);
                [t_ref_TD, A_ref_TD] = Import_for_timfiles([app.InputFileDirectoryEditField.Value,app.ReferenceEditField.Value]);
                
                % Flip time and amplitude based on check boxes
                if app.FlipTimeAxisCheckBox.Value == 1
                    t_sample_TD = -flip(t_sample_TD);
                    A_sample_TD = flip(A_sample_TD);
                    t_ref_TD = -flip(t_ref_TD);
                    A_ref_TD = flip(A_ref_TD);
                end
            
                if app.FlipAmplitudeCheckBox.Value == 1
                    A_sample_TD = -A_sample_TD;
                    A_ref_TD = -A_ref_TD;
                end
                
                % Calculate data for TRTS by adding sample and reference
                if app.TRTSCheckBox.Value == 1
                    try
                        A_sample_TD = A_sample_TD + A_ref_TD;
                    catch
                        errordlg('Sample and reference must have the same number of data points for TRTS analysis.','IO Error');
                        app.TRTSCheckBox.Value = false;
                    end
                end
                
                % Window data
                A_sample_TD_windowed = TD_window(t_sample_TD,A_sample_TD,app.WindowingDropDown.Value,app.WindowWidthpsEditField.Value);
                A_ref_TD_windowed = TD_window(t_ref_TD,A_ref_TD,app.WindowingDropDown.Value,app.WindowWidthpsEditField.Value);
                
                % Plot resulting data
                hold(app.TD_plot,'on');
                plot(app.TD_plot,t_sample_TD,A_sample_TD_windowed);
                plot(app.TD_plot,t_ref_TD,A_ref_TD_windowed);
                hold(app.TD_plot,'off');
                legend(app.TD_plot,'Sample','Reference');
            
                % Fourier Transform and Calculate Power Spectrum of Windowed Data
                input.settings.fft.windowing_type = app.WindowingDropDown.Value;
                input.settings.fft.windowing_width = app.WindowWidthpsEditField.Value;
                input.settings.fft.padding = app.ZeroPadding.Value;
            
                [freq_sample, spec_sample_load] = fft_func(t_sample_TD,A_sample_TD,input.settings.fft);
                [freq_ref, spec_ref_load] = fft_func(t_ref_TD,A_ref_TD,input.settings.fft);
            
                power_sample = abs(spec_sample_load);
                power_ref = abs(spec_ref_load);
            
                % Plot power spectra
                hold(app.Power_plot,'on');
                plot(app.Power_plot,freq_sample,power_sample);
                plot(app.Power_plot,freq_ref,power_ref);
                hold(app.Power_plot,'off');
                legend(app.Power_plot,'Sample','Reference');
            catch
                errordlg('Unable to load and plot data.','IO Error');
            end
        end

        % Button pushed function: BrowseButton_InputDir
        function BrowseButton_InputDirPushed(app, event)
            try
                path_input = uigetdir;
                app.InputFileDirectoryEditField.Value = [path_input,'\'];
            catch
            end
        end

        % Button pushed function: BrowseButton_OutputDir
        function BrowseButton_OutputDirPushed(app, event)
            try
                path_output = uigetdir;
                app.OutputFileDirectoryEditField.Value = [path_output,'\'];
            catch
            end
        end

        % Button pushed function: vButton_SampleFile
        function vButton_SampleFilePushed(app, event)
            try
                input_dir = app.InputFileDirectoryEditField.Value;
                sample_input = uigetfile(strcat(input_dir,'/*.tim'));
                app.SampleEditField.Value = sample_input;
            catch
            end
        end

        % Button pushed function: vButton_ReferenceFile
        function vButton_ReferenceFilePushed(app, event)
            try
                input_dir = app.InputFileDirectoryEditField.Value;
                ref_input = uigetfile(strcat(input_dir,'/*.tim'));
                app.ReferenceEditField.Value = ref_input;
            catch
            end
        end

        % Button pushed function: BrowseInputFileButton
        function BrowseInputFileButtonPushed(app, event)
            try
                input_dir = app.InputFileDirectoryEditField.Value;
                [input_file,input_file_path] = uigetfile(strcat(input_dir,'/*.json'));
                app.InputFileEditField.Value = [input_file_path,input_file];
            catch
            end
        end

        % Button pushed function: LoadInputFileButton
        function LoadInputFileButtonPushed(app, event)
            try
                % Load input json in the input file
                param = load_input(app.InputFileEditField.Value);
                app.WindowingDropDown.Value = param.settings.fft.windowing_type;
                app.WindowWidthpsEditField.Value = param.settings.fft.windowing_width;
                app.ZeroPadding.Value = param.settings.fft.padding;
                app.MinFreqEditField.Value = param.settings.freq_lo;
                app.MaxFreqEditField.Value = param.settings.freq_hi;
                app.FreqStepEditField.Value = param.settings.freq_step;
                app.AmplCutoffEditField.Value = param.settings.a_cut;
                
                app.SampleGeometryTextArea.Value = tf_interpreter_gui(param.sample);
                app.ReferenceGeometryTextArea.Value = tf_interpreter_gui(param.reference);  
            catch
                errordlg('Could not load input file.','IO Error');
            end
        end

        % Button pushed function: SaveInputFileButton
        function SaveInputFileButtonPushed(app, event)
            try
                % Write new json file   
                output_struct.settings.a_cut = app.AmplCutoffEditField.Value;
                output_struct.settings.freq_lo = app.MinFreqEditField.Value;
                output_struct.settings.freq_step = app.FreqStepEditField.Value;
                output_struct.settings.freq_hi = app.MaxFreqEditField.Value;
                output_struct.settings.fft.windowing_type = app.WindowingDropDown.Value;
                output_struct.settings.fft.windowing_width = app.WindowWidthpsEditField.Value;
                output_struct.settings.fft.padding = app.ZeroPadding.Value;
                output_struct.sample = tf_builder_gui(app.SampleGeometryTextArea.Value,false);
                output_struct.reference = tf_builder_gui(app.ReferenceGeometryTextArea.Value,false);
                
                % Open pop-up window to specify filenamen
                [file, path] = uiputfile('*.json','Save New Input File',[app.OutputFilenameEditField.Value,'_input.json']);
                encoded_input = jsonencode(output_struct);
                encoded_input = regexprep(encoded_input, '{', '{\n');
                encoded_input = regexprep(encoded_input, '[', '[\n');
                encoded_input = regexprep(encoded_input, ',', ',\n');
                % Write file
                fileID = fopen([path,file],'w');
                fprintf(fileID,'%s',encoded_input);
                fclose(fileID);
                
                % Change Input File field to newly saved input filename and path
                app.InputFileEditField.Value = [path,file];
            catch
                errordlg('Could not save new parameter file.','IO Error');
            end
        end

        % Button pushed function: SetDefaultButton
        function SetDefaultButtonPushed(app, event)
            try
                % Set Default Param by reading off values in all relevant text boxes
                init_struct.io.input_dir = app.InputFileDirectoryEditField.Value;
                init_struct.io.output_dir = app.OutputFileDirectoryEditField.Value;
                init_struct.io.sample = app.SampleEditField.Value;
                init_struct.io.reference = app.ReferenceEditField.Value;
                init_struct.io.output = app.OutputFilenameEditField.Value;
                init_struct.io.window = app.WindowingDropDown.Value;
                init_struct.io.window_width = app.WindowWidthpsEditField.Value;
                init_struct.io.zero_padding = app.ZeroPadding.Value;
                init_struct.io.flip_time = app.FlipTimeAxisCheckBox.Value;
                init_struct.io.flip_ampl = app.FlipAmplitudeCheckBox.Value;
                init_struct.io.TRTS = app.TRTSCheckBox.Value;
                init_struct.run.file = app.InputFileEditField.Value;
                
                encoded_init = jsonencode(init_struct);
                encoded_init = regexprep(encoded_init, '{', '{\n');
                encoded_init = regexprep(encoded_init, ',', ',\n');
                
                str = which('nelly_main');
                path = str(1:end-12);
                fileID = fopen([path,'initGUI.json'],'w');
                fprintf(fileID,'%s',encoded_init);
                fclose(fileID);
            catch
                errordlg('Could not save new init file for program launch defaults.','IO Error');
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            try
                % Run nelly-main
                % Rerun import routine from the first page for good measure.
                [t_sample_TD, A_sample_TD] = Import_for_timfiles([app.InputFileDirectoryEditField.Value,app.SampleEditField.Value]);
                [t_ref_TD, A_ref_TD] = Import_for_timfiles([app.InputFileDirectoryEditField.Value,app.ReferenceEditField.Value]);
                
                % Flip time and amplitude based on check boxes
                if app.FlipTimeAxisCheckBox.Value == 1
                    t_sample_TD = -flip(t_sample_TD);
                    A_sample_TD = flip(A_sample_TD);
                    t_ref_TD = -flip(t_ref_TD);
                    A_ref_TD = flip(A_ref_TD);
                end
            
                if app.FlipAmplitudeCheckBox.Value == 1
                    A_sample_TD = -A_sample_TD;
                    A_ref_TD = -A_ref_TD;
                end
                
                % Calculate data for TRTS by adding sample and reference
                if app.TRTSCheckBox.Value == 1
                    try
                        A_sample_TD = A_sample_TD + A_ref_TD;
                    catch
                        errordlg('Sample and reference must have the same number of data points for TRTS analysis.','IO Error');
                        app.TRTSCheckBox.Value = false;
                    end
                end
                
                % Build input structure
                input.settings.a_cut = app.AmplCutoffEditField.Value;
                input.settings.freq_lo = app.MinFreqEditField.Value;
                input.settings.freq_step = app.FreqStepEditField.Value;
                input.settings.freq_hi = app.MaxFreqEditField.Value;
                input.settings.fft.windowing_type = app.WindowingDropDown.Value;
                input.settings.fft.windowing_width = app.WindowWidthpsEditField.Value;
                input.settings.fft.padding = app.ZeroPadding.Value;
                input.sample = tf_builder_gui(app.SampleGeometryTextArea.Value,true);
                input.reference = tf_builder_gui(app.ReferenceGeometryTextArea.Value,true);
                
                % Run nelly-main
                [app.freq, app.n_fit, app.freq_full, app.tf_full, app.tf_spec, app.tf_pred, ~, app.spec_smp, app.spec_ref]...
                    = nelly_main(input, t_sample_TD, A_sample_TD, t_ref_TD, A_ref_TD, ...
                    'progress_bar', uiprogressdlg(app.cordouanGUI,'Title','Please Wait',...
        'Message','Running Nelly'));
                
                disp('Run nelly_main completed.');
                
                % Plotting
                cla(app.nk_Plot);
                cla(app.Permittivity_Plot);
                cla(app.AbsCoeff_Plot);
                cla(app.TFmag_Plot);
                cla(app.TFphase_Plot);
                cla(app.SpectraMag_Plot);
                cla(app.SpectraPhase_Plot);
                
                % Plot refractive index
                hold(app.nk_Plot,'on');
                plot(app.nk_Plot,app.freq,real(app.n_fit));
                plot(app.nk_Plot,app.freq,imag(app.n_fit));
                hold(app.nk_Plot,'off');
                legend(app.nk_Plot,'Real (n)','Imag (k)');
                
                % Plot permittivity
                app.eps = permittivity_calc(app.n_fit);
                hold(app.Permittivity_Plot,'on');
                plot(app.Permittivity_Plot,app.freq,real(app.eps));
                plot(app.Permittivity_Plot,app.freq,imag(app.eps));
                hold(app.Permittivity_Plot,'off');
                legend(app.Permittivity_Plot,'Real (\epsilon'')','Imag (\epsilon")');
                
                % Plot absorption coefficient
                app.abs_coeff = abs_coeff_calc(app.freq,app.n_fit);
                hold(app.AbsCoeff_Plot,'on');
                plot(app.AbsCoeff_Plot,app.freq,app.abs_coeff);
                hold(app.AbsCoeff_Plot,'off');
                
                % Plot diagnostics
                % Transfer function comparison
                hold(app.TFmag_Plot,'on');
                plot(app.TFmag_Plot,app.freq,abs(app.tf_spec));
                plot(app.TFmag_Plot,app.freq,abs(app.tf_pred));
                hold(app.TFmag_Plot,'off');
                legend(app.TFmag_Plot,'Experimental','Predicted');
                
                hold(app.TFphase_Plot,'on');
                plot(app.TFphase_Plot,app.freq,unwrap(angle(app.tf_spec)));
                plot(app.TFphase_Plot,app.freq,unwrap(angle(app.tf_pred)));
                hold(app.TFphase_Plot,'off');
                legend(app.TFphase_Plot,'Experimental','Predicted');
                
                % Sample and reference spectra
                hold(app.SpectraMag_Plot,'on');
                plot(app.SpectraMag_Plot,app.freq,abs(app.spec_smp));
                plot(app.SpectraMag_Plot,app.freq,abs(app.spec_ref));
                hold(app.SpectraMag_Plot,'off');
                legend(app.SpectraMag_Plot,'Sample','Reference');
                
                hold(app.SpectraPhase_Plot,'on');
                plot(app.SpectraPhase_Plot,app.freq,unwrap(angle(app.spec_smp)));
                plot(app.SpectraPhase_Plot,app.freq,unwrap(angle(app.spec_ref)));
                hold(app.SpectraPhase_Plot,'off');
                legend(app.SpectraPhase_Plot,'Sample','Reference');
                
                disp('Plotting completed.');
            catch err
                % You broke it. Error handling.
                % I think there is more advanced error handling that can be implemented based on nelly-main
                errordlg(sprintf('Whoa, Nelly! Something went wrong: \n%s\n', err.message),'Nelly Error');
            end
        end

        % Button pushed function: nk_SaveDataButton
        function nk_SaveDataButtonPushed(app, event)
            try
                % Open pop-up window to specify filenamen
                [file, path] = uiputfile('*.txt','Save Refractive Index Data',[app.OutputFileDirectoryEditField.Value,app.OutputFilenameEditField.Value,'_nk.txt']);
                out = [app.freq; real(app.n_fit); imag(app.n_fit)];
                
                % Write file
                fileID = fopen([path,file],'w');
                fprintf(fileID,'Freq (THz)\tn\tk\n');
                fprintf(fileID,'%12.8f\t%12.8f\t%12.8f\n',out);
                fclose(fileID);
            catch
                errordlg('Problem exporting refractive index data.','IO Error');
            end
        end

        % Button pushed function: Permittivity_SaveDataButton
        function Permittivity_SaveDataButtonPushed(app, event)
            try
                % Open pop-up window to specify filenamen
                [file, path] = uiputfile('*.txt','Save Permittivity Data',[app.OutputFileDirectoryEditField.Value,app.OutputFilenameEditField.Value,'_eps.txt']);
                out = [app.freq; real(app.eps); imag(app.eps)];
                
                % Write file
                fileID = fopen([path,file],'w');
                fprintf(fileID,'Freq (THz)\teps''\teps"\n');
                fprintf(fileID,'%12.8f\t%12.8f\t%12.8f\n',out);
                fclose(fileID);
            catch
                errordlg('Problem exporting permittivity data.','IO Error');
            end
        end

        % Button pushed function: AbsCoeff_SaveDataButton
        function AbsCoeff_SaveDataButtonPushed(app, event)
            try
                % Open pop-up window to specify filenamen
                [file, path] = uiputfile('*.txt','Save Absorption Coefficient Data',[app.OutputFileDirectoryEditField.Value,app.OutputFilenameEditField.Value,'_abs_coeff.txt']);
                out = [app.freq; app.abs_coeff];
                
                % Write file
                fileID = fopen([path,file],'w');
                fprintf(fileID,'Freq (THz)\tAbs. Coeff. (cm-1)\n');
                fprintf(fileID,'%12.8f\t%12.8f\n',out);
                fclose(fileID);
            catch
                errordlg('Problem exporting absorption coefficient data.','IO Error');
            end
        end

        % Button pushed function: nk_SaveFigureButton
        function nk_SaveFigureButtonPushed(app, event)
            try
                % Save figure as a .fig file
                % Open pop-up window to specify filenamen
                [file, path] = uiputfile('*.fig','Save As - Refractive Index Figure',[app.OutputFileDirectoryEditField.Value,app.OutputFilenameEditField.Value,'_nk_figure']); 
                fig_nk = figure();
                copied_nk = copyobj(app.nk_Plot, fig_nk);
                set(gcf, 'Position',  [100, 100, 650, 450]);
                saveas(copied_nk,[path,file],'fig');
            catch
                errordlg('Unable to save FIG-file.','IO Error');
            end
        end

        % Button pushed function: Permittivity_SaveFigureButton
        function Permittivity_SaveFigureButtonPushed(app, event)
            try
                % Save figure as a .fig file
                % Open pop-up window to specify filenamen
                [file, path] = uiputfile('*.fig','Save As - Permittivity Figure',[app.OutputFileDirectoryEditField.Value,app.OutputFilenameEditField.Value,'_permittivity_figure']); 
                fig_eps = figure();
                copied_eps = copyobj(app.Permittivity_Plot, fig_eps);
                set(gcf, 'Position',  [100, 100, 650, 450]);
                saveas(copied_eps,[path,file],'fig');
            catch
                errordlg('Unable to save FIG-file.','IO Error');
            end
        end

        % Button pushed function: AbsCoeff_SaveFigureButton
        function AbsCoeff_SaveFigureButtonPushed(app, event)
            try
                % Save figure as a .fig file
                % Open pop-up window to specify filenamen
                [file, path] = uiputfile('*.fig','Save As - Absorption Coefficient Figure',[app.OutputFileDirectoryEditField.Value,app.OutputFilenameEditField.Value,'_permittivity_figure']); 
                fig_abs = figure();
                copied_abs = copyobj(app.AbsCoeff_Plot, fig_abs);
                set(gcf, 'Position',  [100, 100, 650, 450]);
                saveas(copied_abs,[path,file],'fig');
            catch
                errordlg('Unable to save FIG-file.','IO Error');
            end
        end

        % Button pushed function: DiagnosticsSpectra_SaveDataButton
        function DiagnosticsSpectra_SaveDataButtonPushed(app, event)
            try
                % Save diagnostics data
                % Open pop-up window to specify filenamen
                [file, path] = uiputfile('*.txt','Save As - Spectral Diagnostics Data',[app.OutputFileDirectoryEditField.Value,app.OutputFilenameEditField.Value,'_diagnostics.txt']);
                out = [app.freq; abs(app.spec_smp); unwrap(angle(app.spec_smp)); ...
                                 abs(app.spec_ref); unwrap(angle(app.spec_ref))];
                
                % Write file
                fileID = fopen([path,file],'w');
                fprintf(fileID,'Freq (THz)\tMag. Sample\tPhase Sample (rad.)\tMag. Ref.\tPhase Ref. (rad.)\n');
                fprintf(fileID,'%12.8f\t%12.8f\t%12.8f\t%12.8f\t%12.8f\n',out);
                fclose(fileID);
            catch
                errordlg('Unable to save diagnostics data to file','IO Error');
            end
        end

        % Button pushed function: DiagnosticsTF_SaveDataButton
        function DiagnosticsTF_SaveDataButtonPushed(app, event)
            try
                % Save diagnostics data
                % Open pop-up window to specify filenamen
                [file, path] = uiputfile('*.txt','Save As - Transfer Function Diagnostics Data',[app.OutputFileDirectoryEditField.Value,app.OutputFilenameEditField.Value,'_diagnostics.txt']);
                out = [app.freq; abs(app.tf_spec); unwrap(angle(app.tf_spec)); ...
                                 abs(app.tf_pred); unwrap(angle(app.tf_pred))];
                
                % Write file
                fileID = fopen([path,file],'w');
                fprintf(fileID,'Freq (THz)\tMag. TF Meas.\tPhase TF Meas. (rad.)\tMag. TF Pred.\tPhase TF Pref. (rad.)\n');
                fprintf(fileID,'%12.8f\t%12.8f\t%12.8f\t%12.8f\t%12.8f\n',out);
                fclose(fileID);
            catch
                errordlg('Unable to save diagnostics data to file','IO Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.cordouanGUI = uifigure('Visible', 'off');
            app.cordouanGUI.Name = 'Cordouan - A GUI for Nelly - v0.0';
            app.TabGroup = uitabgroup(app.cordouanGUI);
            app.DataIO = uitab(app.TabGroup);
            app.DataIO.Title = 'Data Input and Output';
            app.TD_plot = uiaxes(app.DataIO);
            title(app.TD_plot, 'Time Domain')
            xlabel(app.TD_plot, 't_{probe} (ps)')
            ylabel(app.TD_plot, 'Amplitude (a.u.)')
            app.Power_plot = uiaxes(app.DataIO);
            title(app.Power_plot, 'Power Spectrum')
            xlabel(app.Power_plot, 'Frequency (THz)')
            ylabel(app.Power_plot, 'Power (a.u.)')
            app.Power_plot.XLim = [0.1 10];
            app.Power_plot.YScale = 'log';
            app.BrowseButton_InputDir = uibutton(app.DataIO, 'push');
            app.BrowseButton_InputDir.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_InputDirPushed, true);
            app.BrowseButton_InputDir.Text = 'Browse';
            app.BrowseButton_OutputDir = uibutton(app.DataIO, 'push');
            app.BrowseButton_OutputDir.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_OutputDirPushed, true);
            app.BrowseButton_OutputDir.Text = 'Browse';
            app.vButton_SampleFile = uibutton(app.DataIO, 'push');
            app.vButton_SampleFile.ButtonPushedFcn = createCallbackFcn(app, @vButton_SampleFilePushed, true);
            app.vButton_SampleFile.Text = 'v';
            app.vButton_ReferenceFile = uibutton(app.DataIO, 'push');
            app.vButton_ReferenceFile.ButtonPushedFcn = createCallbackFcn(app, @vButton_ReferenceFilePushed, true);
            app.vButton_ReferenceFile.Text = 'v';
            app.InputFileDirectoryEditFieldLabel = uilabel(app.DataIO);
            app.InputFileDirectoryEditFieldLabel.Text = 'Input File Directory';
            app.InputFileDirectoryEditField = uieditfield(app.DataIO, 'text');
            app.OutputFileDirectoryEditFieldLabel = uilabel(app.DataIO);
            app.OutputFileDirectoryEditFieldLabel.Text = 'Output File Directory';
            app.OutputFileDirectoryEditField = uieditfield(app.DataIO, 'text');
            app.SampleEditFieldLabel = uilabel(app.DataIO);
            app.SampleEditFieldLabel.Text = 'Sample';
            app.SampleEditField = uieditfield(app.DataIO, 'text');
            app.ReferenceEditFieldLabel = uilabel(app.DataIO);
            app.ReferenceEditFieldLabel.Text = 'Reference';
            app.ReferenceEditField = uieditfield(app.DataIO, 'text');
            app.OutputFilenameEditFieldLabel = uilabel(app.DataIO);
            app.OutputFilenameEditFieldLabel.Text = 'Output Filename';
            app.OutputFilenameEditField = uieditfield(app.DataIO, 'text');
            app.LOADPLOTButton = uibutton(app.DataIO, 'push');
            app.LOADPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @LOADPLOTButtonPushed, true);
            app.LOADPLOTButton.Text = 'LOAD & PLOT';
            app.Label = uilabel(app.DataIO);
            app.Label.Text = '2';
            app.FlipTimeAxisCheckBox = uicheckbox(app.DataIO);
            app.FlipTimeAxisCheckBox.Text = 'Flip Time Axis';
            app.FlipAmplitudeCheckBox = uicheckbox(app.DataIO);
            app.FlipAmplitudeCheckBox.Text = 'Flip Amplitude';
            app.WindowingDropDownLabel = uilabel(app.DataIO);
            app.WindowingDropDownLabel.Text = 'Windowing';
            app.WindowingDropDown = uidropdown(app.DataIO);
            app.WindowingDropDown.Items = {'none', 'gauss', 'square'};
            app.WindowingDropDown.Value = 'none';
            app.WindowWidthpsEditFieldLabel = uilabel(app.DataIO);
            app.WindowWidthpsEditFieldLabel.Text = 'Window Width (ps)';
            app.WindowWidthpsEditField = uieditfield(app.DataIO, 'numeric');
            app.WindowWidthpsEditField.Value = 6.5;
            app.ZeroPaddingLabel = uilabel(app.DataIO);
            app.ZeroPaddingLabel.Text = 'Zero Padding';
            app.ZeroPadding = uieditfield(app.DataIO, 'numeric');
            app.ZeroPadding.Value = 18;
            app.TRTSCheckBox = uicheckbox(app.DataIO);
            app.TRTSCheckBox.Text = 'TRTS';
            app.SetDefaultButton = uibutton(app.DataIO, 'push');
            app.SetDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultButtonPushed, true);
            app.SetDefaultButton.Text = 'Set Default';
            app.RunParam = uitab(app.TabGroup);
            app.RunParam.Title = 'Run Parameters';
            app.FrequencyRangeLabel = uilabel(app.RunParam);
            app.FrequencyRangeLabel.Text = 'Frequency Range';
            app.TransferFunctionBuilderLabel = uilabel(app.RunParam);
            app.TransferFunctionBuilderLabel.Text = 'Transfer Function Builder';
            app.RUNButton = uibutton(app.RunParam, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.MinFreqTHzEditFieldLabel = uilabel(app.RunParam);
            app.MinFreqTHzEditFieldLabel.Text = 'Min Freq. (THz)';
            app.MinFreqEditField = uieditfield(app.RunParam, 'numeric');
            app.MinFreqEditField.Value = 0.2;
            app.MaxFreqTHzEditFieldLabel = uilabel(app.RunParam);
            app.MaxFreqTHzEditFieldLabel.Text = 'Max Freq. (THz)';
            app.MaxFreqEditField = uieditfield(app.RunParam, 'numeric');
            app.MaxFreqEditField.Value = 2.5;
            app.FreqStepTHzEditFieldLabel = uilabel(app.RunParam);
            app.FreqStepTHzEditFieldLabel.Text = 'Freq. Step (THz)';
            app.FreqStepEditField = uieditfield(app.RunParam, 'numeric');
            app.FreqStepEditField.Value = 0.05;
            app.SampleGeometryTextAreaLabel = uilabel(app.RunParam);
            app.SampleGeometryTextAreaLabel.Text = 'Sample Geometry';
            app.SampleGeometryTextArea = uitextarea(app.RunParam);
            app.ReferenceGeometryTextAreaLabel = uilabel(app.RunParam);
            app.ReferenceGeometryTextAreaLabel.Text = 'Reference Geometry';
            app.ReferenceGeometryTextArea = uitextarea(app.RunParam);
            app.BrowseInputFileButton = uibutton(app.RunParam, 'push');
            app.BrowseInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputFileButtonPushed, true);
            app.BrowseInputFileButton.Text = 'Browse';
            app.InputFileEditFieldLabel = uilabel(app.RunParam);
            app.InputFileEditFieldLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.RunParam, 'text');
            app.TFBuilderLabel = uilabel(app.RunParam);
            app.TFBuilderLabel.Text = 'Use formatting as shown in example, thicknesses (d) are in micrometers, "solve" for your sample refractive index.';
            app.LoadInputFileButton = uibutton(app.RunParam, 'push');
            app.LoadInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInputFileButtonPushed, true);
            app.LoadInputFileButton.Text = 'Load';
            app.TreeLabel = uilabel(app.RunParam);
            app.TreeLabel.Text = 'Tree Parameter';
            app.AmplitudeCutoffLabel = uilabel(app.RunParam);
            app.AmplitudeCutoffLabel.Text = 'Amplitude Cutoff';
            app.AmplCutoffEditField = uieditfield(app.RunParam, 'numeric');
            app.AmplCutoffEditField.Value = 6e-05;
            app.SaveInputFileButton = uibutton(app.RunParam, 'push');
            app.SaveInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInputFileButtonPushed, true);
            app.SaveInputFileButton.Text = 'Save Input File';
            app.DataAnalysis = uitab(app.TabGroup);
            app.DataAnalysis.Title = 'Plotting and Analysis';
            app.PlotTypes = uitabgroup(app.DataAnalysis);
            app.nk = uitab(app.PlotTypes);
            app.nk.Title = 'Refractive Index';
            app.nk_Plot = uiaxes(app.nk);
            xlabel(app.nk_Plot, 'Frequency (THz)')
            ylabel(app.nk_Plot, 'Complex Refractive Index')
            app.nk_SaveDataButton = uibutton(app.nk, 'push');
            app.nk_SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @nk_SaveDataButtonPushed, true);
            app.nk_SaveDataButton.Text = 'Save Data';
            app.nk_SaveFigureButton = uibutton(app.nk, 'push');
            app.nk_SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @nk_SaveFigureButtonPushed, true);
            app.nk_SaveFigureButton.Text = 'Save FIG';
            app.Permittivty = uitab(app.PlotTypes);
            app.Permittivty.Title = 'Permittivity';
            app.Permittivity_Plot = uiaxes(app.Permittivty);
            xlabel(app.Permittivity_Plot, 'Frequency (THz)')
            ylabel(app.Permittivity_Plot, 'Complex Permittivity')
            app.Permittivity_SaveDataButton = uibutton(app.Permittivty, 'push');
            app.Permittivity_SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @Permittivity_SaveDataButtonPushed, true);
            app.Permittivity_SaveDataButton.Text = 'Save Data';
            app.Permittivity_SaveFigureButton = uibutton(app.Permittivty, 'push');
            app.Permittivity_SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @Permittivity_SaveFigureButtonPushed, true);
            app.Permittivity_SaveFigureButton.Text = 'Save FIG';
            app.AbsCoeff = uitab(app.PlotTypes);
            app.AbsCoeff.Title = 'Absorption Coefficient';
            app.AbsCoeff_Plot = uiaxes(app.AbsCoeff);
            xlabel(app.AbsCoeff_Plot, 'Frequency (THz)')
            ylabel(app.AbsCoeff_Plot, 'Absorption Coefficient (cm^{-1})')
            app.AbsCoeff_Plot.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AbsCoeff_SaveDataButton = uibutton(app.AbsCoeff, 'push');
            app.AbsCoeff_SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @AbsCoeff_SaveDataButtonPushed, true);
            app.AbsCoeff_SaveDataButton.Text = 'Save Data';
            app.AbsCoeff_SaveFigureButton = uibutton(app.AbsCoeff, 'push');
            app.AbsCoeff_SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @AbsCoeff_SaveFigureButtonPushed, true);
            app.AbsCoeff_SaveFigureButton.Text = 'Save FIG';
            app.Diagnostics = uitab(app.PlotTypes);
            app.Diagnostics.Title = 'Diagnostics';
            app.TFmag_Plot = uiaxes(app.Diagnostics);
            title(app.TFmag_Plot, 'Transfer Function Comparison')
            xlabel(app.TFmag_Plot, 'Frequency (THz)')
            ylabel(app.TFmag_Plot, 'Magnitude')
            app.TFphase_Plot = uiaxes(app.Diagnostics);
            xlabel(app.TFphase_Plot, 'Frequency (THz)')
            ylabel(app.TFphase_Plot, 'Phase')
            app.SpectraMag_Plot = uiaxes(app.Diagnostics);
            title(app.SpectraMag_Plot, 'Sample and Reference Spectra')
            xlabel(app.SpectraMag_Plot, 'Frequency (THz)')
            ylabel(app.SpectraMag_Plot, 'Magnitude')
            app.SpectraPhase_Plot = uiaxes(app.Diagnostics);
            xlabel(app.SpectraPhase_Plot, 'Frequency (THz)')
            ylabel(app.SpectraPhase_Plot, 'Phase')
            app.DiagnosticsSpectra_SaveDataButton = uibutton(app.Diagnostics, 'push');
            app.DiagnosticsSpectra_SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticsSpectra_SaveDataButtonPushed, true);
            app.DiagnosticsSpectra_SaveDataButton.Text = 'Save Spectra';
            app.DiagnosticsTF_SaveDataButton = uibutton(app.Diagnostics, 'push');
            app.DiagnosticsTF_SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticsTF_SaveDataButtonPushed, true);
            app.DiagnosticsTF_SaveDataButton.Text = 'Save Transfer Functions';
            % Show the figure after all components are created
            app.cordouanGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cordouan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.cordouanGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.cordouanGUI)
        end
    end
end