classdef cordouan_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Cordouan                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataInputandOutputTab           matlab.ui.container.Tab
        InputFileDirectoryEditFieldLabel  matlab.ui.control.Label
        InputFileDirectoryEditField     matlab.ui.control.EditField
        OutputFileDirectoryEditFieldLabel  matlab.ui.control.Label
        OutputFileDirectoryEditField    matlab.ui.control.EditField
        SampleEditFieldLabel            matlab.ui.control.Label
        SampleEditField                 matlab.ui.control.EditField
        ReferenceEditFieldLabel         matlab.ui.control.Label
        ReferenceEditField              matlab.ui.control.EditField
        OutputFilenameEditFieldLabel    matlab.ui.control.Label
        OutputFilenameEditField         matlab.ui.control.EditField
        TD_plot                         matlab.ui.control.UIAxes
        Power_plot                      matlab.ui.control.UIAxes
        WindowingDropDownLabel          matlab.ui.control.Label
        WindowingDropDown               matlab.ui.control.DropDown
        WindowWidthpsEditFieldLabel     matlab.ui.control.Label
        WindowWidthpsEditField          matlab.ui.control.NumericEditField
        LOADPLOTButton                  matlab.ui.control.Button
        BrowseButton_InputDir           matlab.ui.control.Button
        BrowseButton_OutputDir          matlab.ui.control.Button
        vButton_SampleFile              matlab.ui.control.Button
        vButton_ReferenceFile           matlab.ui.control.Button
        ZeroPaddingLabel                matlab.ui.control.Label
        ZeroPadding                     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        FlipTimeAxisCheckBox            matlab.ui.control.CheckBox
        FlipAmplitudeCheckBox           matlab.ui.control.CheckBox
        SimulationParametersTab         matlab.ui.container.Tab
        MinFreqTHzEditFieldLabel        matlab.ui.control.Label
        MinFreqTHzEditField             matlab.ui.control.NumericEditField
        MaxFreqTHzEditFieldLabel        matlab.ui.control.Label
        MaxFreqTHzEditField             matlab.ui.control.NumericEditField
        FreqStepTHzEditFieldLabel       matlab.ui.control.Label
        FreqStepTHzEditField            matlab.ui.control.NumericEditField
        FrequencyRangeLabel             matlab.ui.control.Label
        TransferFunctionBuilderLabel    matlab.ui.control.Label
        InputFileEditFieldLabel         matlab.ui.control.Label
        InputFileEditField              matlab.ui.control.EditField
        BrowseButton_InputFile          matlab.ui.control.Button
        UseInputFileCheckBox            matlab.ui.control.CheckBox
        SampleGeometryTextArea_2Label   matlab.ui.control.Label
        SampleGeometryTextArea          matlab.ui.control.TextArea
        ReferenceGeometryTextAreaLabel  matlab.ui.control.Label
        ReferenceGeometryTextArea       matlab.ui.control.TextArea
        DOMETHButton                    matlab.ui.control.Button
        ReverseOptimizationCheckBox     matlab.ui.control.CheckBox
        OtherSimulationOptionsLabel     matlab.ui.control.Label
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        EngageMAXIMUMOVERDRIVECheckBox  matlab.ui.control.CheckBox
        UseformattingasshowninexamplethicknessesdareinmicrometersLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogApp % Dialog box app
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % What to do when app starts
        end

        % Callback function: InputFileDirectoryEditField, 
        % InputFileDirectoryEditField, LOADPLOTButton
        function LOADPLOTButtonPushed(app, event)
            %% What to do when button is pushed for plot and load
            app.TD_plot.cla; % Clears TD plot
            app.Power_plot.cla; % Clears power spectrum plot
            % Import data
            input_sample = strcat(app.InputFileDirectoryEditField.Value,'\',app.SampleEditField.Value)
            [t_sample_TD, A_sample_TD] = Import_for_timfiles(input_sample);
            input_reference = strcat(app.InputFileDirectoryEditField.Value,'\',app.ReferenceEditField.Value)
            [t_ref_TD, A_ref_TD] = Import_for_timfiles(input_reference);
            
            % Flip time and amplitude based on check boxes
            if app.FlipTimeAxisCheckBox.Value == 1
                t_sample_TD = -flip(t_sample_TD);
                A_sample_TD = flip(A_sample_TD);
                t_ref_TD = -flip(t_ref_TD);
                A_ref_TD = flip(A_ref_TD);
            end
            
            if app.FlipAmplitudeCheckBox.Value == 1
                A_sample_TD = -A_sample_TD;
                A_ref_TD = -A_ref_TD;
            end
            
            % Window data based on drop-down options
            if strcmp(app.WindowingDropDown.Value,'Gaussian')
                type = 'gauss';
            elseif strcmp(app.WindowingDropDown.Value,'Square')
                type = 'square';
            else
                type = 'none';
            end
                
            A_sample_TD_windowed = TD_window(t_sample_TD,A_sample_TD,type,app.WindowWidthpsEditField.Value);
            A_ref_TD_windowed = TD_window(t_ref_TD,A_ref_TD,type,app.WindowWidthpsEditField.Value);
            
            % Plot resulting data
            hold(app.TD_plot,'on');
            plot(app.TD_plot,t_sample_TD,A_sample_TD_windowed);
            plot(app.TD_plot,t_ref_TD,A_ref_TD_windowed);
            hold(app.TD_plot,'off');
            legend(app.TD_plot,'Sample','Reference');
            
            %% Fourier Transform and Calculate Power Spectrum of Windowed Data
            
            input.settings.fft.windowing_type = type;
            input.settings.fft.windowing_width = app.WindowWidthpsEditField.Value;
            input.settings.fft.padding = app.ZeroPadding.Value;
            
            [freq_sample, spec_sample] = fft_func(t_sample_TD,A_sample_TD,input.settings.fft);
            [freq_ref, spec_ref] = fft_func(t_ref_TD,A_ref_TD,input.settings.fft);
            
            power_sample = abs(spec_sample);
            power_ref = abs(spec_ref);
            
            % Plot power spectra
            hold(app.Power_plot,'on');
            plot(app.Power_plot,freq_sample,power_sample);
            plot(app.Power_plot,freq_ref,power_ref);
            hold(app.Power_plot,'off');
            legend(app.Power_plot,'Sample','Reference');
            
            
        end

        % Callback function
        function DoNotPressButtonPushed(app, event)
            %% terminate code
            warning = 'Why did you press?'
            check = num2str(input.settings.fft.padding)
        end

        % Button pushed function: BrowseButton_InputDir
        function BrowseButton_InputDirPushed(app, event)
            path_input = uigetdir;
            app.InputFileDirectoryEditField.Value = path_input;
        end

        % Button pushed function: BrowseButton_OutputDir
        function BrowseButton_OutputDirPushed(app, event)
            path_output = uigetdir;
            app.OutputFileDirectoryEditField.Value = path_output;
        end

        % Button pushed function: vButton_SampleFile
        function vButton_SampleFilePushed(app, event)
            input_dir = app.InputFileDirectoryEditField.Value;
            sample_input = uigetfile(strcat(input_dir,'/*.tim'));
            app.SampleEditField.Value = sample_input;
        end

        % Button pushed function: vButton_ReferenceFile
        function vButton_ReferenceFilePushed(app, event)
            input_dir = app.InputFileDirectoryEditField.Value;
            ref_input = uigetfile(strcat(input_dir,'/*.tim'));
            app.ReferenceEditField.Value = ref_input;
        end

        % Button pushed function: BrowseButton_InputFile
        function BrowseButton_InputFilePushed(app, event)
            input_file_dir = app.InputFileDirectoryEditField.Value;
            sample_file_input = uigetfile(strcat(input_file_dir,'/*.json'));
            app.SampleEditField.Value = sample_file_input;
        end

        % Value changed function: UseInputFileCheckBox
        function UseInputFileCheckBoxValueChanged(app, event)
            %value = app.UseInputFileCheckBox.Value;
            
        end

        % Button pushed function: DOMETHButton
        function DOMETHButtonPushed(app, event)
            % Import data
            input_sample = strcat(app.InputFileDirectoryEditField.Value,'\',app.SampleEditField.Value)
            [t_smp, A_smp] = Import_for_timfiles(input_sample);
            input_reference = strcat(app.InputFileDirectoryEditField.Value,'\',app.ReferenceEditField.Value)
            [t_ref, A_ref] = Import_for_timfiles(input_reference);
            
            % Flip time and amplitude based on check boxes
            if app.FlipTimeAxisCheckBox.Value == 1
                t_smp = -flip(t_smp);
                A_smp = flip(A_smp);
                t_ref = -flip(t_ref);
                A_ref = flip(A_ref);
            end
            
            if app.FlipAmplitudeCheckBox.Value == 1
                A_smp = -A_smp;
                A_ref = -A_ref;
            end
            
            % Window data based on drop-down options
            if strcmp(app.WindowingDropDown.Value,'Gaussian')
                type = 'gauss';
            elseif strcmp(app.WindowingDropDown.Value,'Square')
                type = 'square';
            else
                type = 'none';
            end
            
            input.settings.fft.windowing_type = type;
            input.settings.fft.windowing_width = app.WindowWidthpsEditField.Value;
            input.settings.fft.padding = app.ZeroPadding.Value;
            input.settings.freq_hi = app.MaxFreqTHzEditField.Value;
            input.settings.freq_lo = app.MinFreqTHzEditField.Value;
            input.settings.freq_step = app.FreqStepTHzEditField.Value;
            
            input.sample = tf_builder_gui(app.SampleGeometryTextArea.Value);
            input.reference = tf_builder_gui(app.ReferenceGeometryTextArea.Value);
            
            [freq, n_fit, freq_full, tf_full, tf_spec, tf_pred, func, spec_smp, spec_ref]...
                    = nelly_main(input, t_smp, A_smp, t_ref, A_ref);
            
            app.DialogApp = nelly_plotting(app,app.OutputFileDirectoryEditField.Value,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Cordouan = uifigure;
            app.Cordouan.Name = 'Cordouan - Interactive THz Data Processing';
            app.TabGroup = uitabgroup(app.Cordouan);
            app.DataInputandOutputTab = uitab(app.TabGroup);
            app.DataInputandOutputTab.Title = 'Data Input and Output';
            app.InputFileDirectoryEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.InputFileDirectoryEditFieldLabel.Text = 'Input File Directory';
            app.InputFileDirectoryEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.InputFileDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @LOADPLOTButtonPushed, true);
            app.InputFileDirectoryEditField.ValueChangingFcn = createCallbackFcn(app, @LOADPLOTButtonPushed, true);
            app.InputFileDirectoryEditField.Value = 'C:\Users\jacob\Documents\Schmuttenmaer Group\Datas\nelly_test';
            app.OutputFileDirectoryEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.OutputFileDirectoryEditFieldLabel.Text = 'Output File Directory';
            app.OutputFileDirectoryEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.OutputFileDirectoryEditField.Value = 'C:\Users\jacob\Documents\Schmuttenmaer Group\Datas\nelly_test\output';
            app.SampleEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.SampleEditFieldLabel.Text = 'Sample';
            app.SampleEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.SampleEditField.Value = 'Jul30_008.tim';
            app.ReferenceEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.ReferenceEditFieldLabel.Text = 'Reference';
            app.ReferenceEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.ReferenceEditField.Value = 'Jul30_009.tim';
            app.OutputFilenameEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.OutputFilenameEditFieldLabel.Text = 'Output Filename';
            app.OutputFilenameEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.OutputFilenameEditField.Value = 'test_output.txt';
            app.TD_plot = uiaxes(app.DataInputandOutputTab);
            title(app.TD_plot, 'Time Domain')
            xlabel(app.TD_plot, 't_{probe} (ps)')
            ylabel(app.TD_plot, 'Amplitude (a.u.)')
            app.Power_plot = uiaxes(app.DataInputandOutputTab);
            title(app.Power_plot, 'Power Spectrum')
            xlabel(app.Power_plot, 'Frequency (THz)')
            ylabel(app.Power_plot, 'Power (a.u.)')
            app.Power_plot.XLim = [0.1 10];
            app.Power_plot.YScale = 'log';
            app.WindowingDropDownLabel = uilabel(app.DataInputandOutputTab);
            app.WindowingDropDownLabel.Text = 'Windowing';
            app.WindowingDropDown = uidropdown(app.DataInputandOutputTab);
            app.WindowingDropDown.Items = {'None', 'Gaussian', 'Square'};
            app.WindowingDropDown.Value = 'Gaussian';
            app.WindowWidthpsEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.WindowWidthpsEditFieldLabel.Text = 'Window Width (ps)';
            app.WindowWidthpsEditField = uieditfield(app.DataInputandOutputTab, 'numeric');
            app.WindowWidthpsEditField.Value = 6.5;
            app.LOADPLOTButton = uibutton(app.DataInputandOutputTab, 'push');
            app.LOADPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @LOADPLOTButtonPushed, true);
            app.LOADPLOTButton.Text = 'LOAD & PLOT';
            app.BrowseButton_InputDir = uibutton(app.DataInputandOutputTab, 'push');
            app.BrowseButton_InputDir.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_InputDirPushed, true);
            app.BrowseButton_InputDir.Text = 'Browse';
            app.BrowseButton_OutputDir = uibutton(app.DataInputandOutputTab, 'push');
            app.BrowseButton_OutputDir.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_OutputDirPushed, true);
            app.BrowseButton_OutputDir.Text = 'Browse';
            app.vButton_SampleFile = uibutton(app.DataInputandOutputTab, 'push');
            app.vButton_SampleFile.ButtonPushedFcn = createCallbackFcn(app, @vButton_SampleFilePushed, true);
            app.vButton_SampleFile.Text = 'v';
            app.vButton_ReferenceFile = uibutton(app.DataInputandOutputTab, 'push');
            app.vButton_ReferenceFile.ButtonPushedFcn = createCallbackFcn(app, @vButton_ReferenceFilePushed, true);
            app.vButton_ReferenceFile.Text = 'v';
            app.ZeroPaddingLabel = uilabel(app.DataInputandOutputTab);
            app.ZeroPaddingLabel.Text = 'Zero Padding';
            app.ZeroPadding = uieditfield(app.DataInputandOutputTab, 'numeric');
            app.ZeroPadding.Value = 18;
            app.Label = uilabel(app.DataInputandOutputTab);
            app.Label.Text = '2';
            app.FlipTimeAxisCheckBox = uicheckbox(app.DataInputandOutputTab);
            app.FlipTimeAxisCheckBox.Text = 'Flip Time Axis';
            app.FlipTimeAxisCheckBox.Value = true;
            app.FlipAmplitudeCheckBox = uicheckbox(app.DataInputandOutputTab);
            app.FlipAmplitudeCheckBox.Text = 'Flip Amplitude';
            app.FlipAmplitudeCheckBox.Value = true;
            app.SimulationParametersTab = uitab(app.TabGroup);
            app.SimulationParametersTab.Title = 'Simulation Parameters';
            app.MinFreqTHzEditFieldLabel = uilabel(app.SimulationParametersTab);
            app.MinFreqTHzEditFieldLabel.Text = 'Min Freq. (THz)';
            app.MinFreqTHzEditField = uieditfield(app.SimulationParametersTab, 'numeric');
            app.MinFreqTHzEditField.Value = 0.2;
            app.MaxFreqTHzEditFieldLabel = uilabel(app.SimulationParametersTab);
            app.MaxFreqTHzEditFieldLabel.Text = 'Max Freq. (THz)';
            app.MaxFreqTHzEditField = uieditfield(app.SimulationParametersTab, 'numeric');
            app.MaxFreqTHzEditField.Value = 2.5;
            app.FreqStepTHzEditFieldLabel = uilabel(app.SimulationParametersTab);
            app.FreqStepTHzEditFieldLabel.Text = 'Freq. Step (THz)';
            app.FreqStepTHzEditField = uieditfield(app.SimulationParametersTab, 'numeric');
            app.FreqStepTHzEditField.Value = 0.05;
            app.FrequencyRangeLabel = uilabel(app.SimulationParametersTab);
            app.FrequencyRangeLabel.Text = 'Frequency Range';
            app.TransferFunctionBuilderLabel = uilabel(app.SimulationParametersTab);
            app.TransferFunctionBuilderLabel.Text = 'Transfer Function Builder';
            app.InputFileEditFieldLabel = uilabel(app.SimulationParametersTab);
            app.InputFileEditFieldLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.SimulationParametersTab, 'text');
            app.BrowseButton_InputFile = uibutton(app.SimulationParametersTab, 'push');
            app.BrowseButton_InputFile.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_InputFilePushed, true);
            app.BrowseButton_InputFile.Text = 'Browse';
            app.UseInputFileCheckBox = uicheckbox(app.SimulationParametersTab);
            app.UseInputFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseInputFileCheckBoxValueChanged, true);
            app.UseInputFileCheckBox.Text = 'Use Input File?';
            app.SampleGeometryTextArea_2Label = uilabel(app.SimulationParametersTab);
            app.SampleGeometryTextArea_2Label.Text = 'Sample Geometry';
            app.SampleGeometryTextArea = uitextarea(app.SimulationParametersTab);
            app.SampleGeometryTextArea.Value = {'air, d=0, n=1'; 'quartz, d=1230, n=quartz_ref.txt'; 'sample, d=100, n=solve'; 'quartz, d=1215, n=quartz_ref.txt'; 'air, d=0, n=1'};
            app.ReferenceGeometryTextAreaLabel = uilabel(app.SimulationParametersTab);
            app.ReferenceGeometryTextAreaLabel.Text = 'Reference Geometry';
            app.ReferenceGeometryTextArea = uitextarea(app.SimulationParametersTab);
            app.ReferenceGeometryTextArea.Value = {'air, d=0, n=1'; 'quartz, d=1230, n=quartz_ref.txt'; 'air, d=100, n=1'; 'quartz, d=1215, n=quartz_ref.txt'; 'air, d=0, n=1'};
            app.DOMETHButton = uibutton(app.SimulationParametersTab, 'push');
            app.DOMETHButton.ButtonPushedFcn = createCallbackFcn(app, @DOMETHButtonPushed, true);
            app.DOMETHButton.Text = 'DO METH!';
            app.ReverseOptimizationCheckBox = uicheckbox(app.SimulationParametersTab);
            app.ReverseOptimizationCheckBox.Text = '   Reverse Optimization';
            app.OtherSimulationOptionsLabel = uilabel(app.SimulationParametersTab);
            app.OtherSimulationOptionsLabel.Text = 'Other Simulation Options';
            app.AlgorithmDropDownLabel = uilabel(app.SimulationParametersTab);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.SimulationParametersTab);
            app.AlgorithmDropDown.Items = {'Dogs', 'Cats'};
            app.AlgorithmDropDown.Value = 'Dogs';
            app.EngageMAXIMUMOVERDRIVECheckBox = uicheckbox(app.SimulationParametersTab);
            app.EngageMAXIMUMOVERDRIVECheckBox.Text = '   Engage MAXIMUM OVERDRIVE';
            app.UseformattingasshowninexamplethicknessesdareinmicrometersLabel = uilabel(app.SimulationParametersTab);
            app.UseformattingasshowninexamplethicknessesdareinmicrometersLabel.Text = 'Use formatting as shown in example, thicknesses (d) are in micrometers, "solve" for your sample refractive index.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = cordouan_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Cordouan)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Cordouan)
        end
    end
end